classdef basic_image_processing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        MagnitudeofGradientButton    matlab.ui.control.Button
        CrossCorrelationButton       matlab.ui.control.Button
        HistogramEqualizationButton  matlab.ui.control.Button
        HoughTransformButton         matlab.ui.control.Button
        EdgeDetectionButton          matlab.ui.control.Button
        ImageThresholdingButton      matlab.ui.control.Button
        CannyOperationButton         matlab.ui.control.Button
        LoadImageButton              matlab.ui.control.Button
        ImageHistogramButton         matlab.ui.control.Button
        MedianFilteringButton        matlab.ui.control.Button
        GrayscaleButton              matlab.ui.control.Button
        BASICIMAGEPROCESSINGLabel    matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OriginalImage   % Original image
        NoisyImage      % Noisy image
        FilteredImage   % Filtered image
        GrayscaleImage  % Grayscale image
        WebcamObj % camera 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'});
            if isequal(file, 0)
                return; % User canceled
            end
            app.OriginalImage = imread(fullfile(path, file)); 
            imshow(app.OriginalImage, 'Parent', app.UIAxes);
            title(app.UIAxes, 'Original Image')
        end

        % Button pushed function: GrayscaleButton
        function GrayscaleButtonPushed(app, event)
            if isempty(app.OriginalImage)
                errordlg('Please load an image first.', 'Error');
                return;
            end
            % Check if the image is already grayscale
            if size(app.OriginalImage, 3) == 3
            % Convert to grayscale if the image is in RGB format
            app.GrayscaleImage = rgb2gray(app.OriginalImage);
            else
            % If the image is already grayscale, just assign it
            app.GrayscaleImage = app.OriginalImage;
            end
            % Create a new figure for the grayscale image
            figure; % Create a new figure
            ax = axes; % Create new axes in the figure
            imshow(app.GrayscaleImage, 'Parent', ax); % Display the grayscale image in the new axes
            title(ax, 'Grayscale Image'); % Set the title for the axes
        end

        % Button pushed function: MedianFilteringButton
        function MedianFilteringButtonPushed(app, event)
            
            if isempty(app.OriginalImage)
                errordlg('Please load an image first.', 'Error');
                return;
            end
            % Add salt-and-pepper noise
            noiseDensity = 0.02; % Adjust the density of the noise (0 to 1)
            app.NoisyImage = imnoise(app.GrayscaleImage, 'salt & pepper', noiseDensity);

             % Define the size of the median filter (e.g., 3x3)
             filterSize = 3;

            % Apply median filtering
            app.FilteredImage = medfilt2(app.NoisyImage, [filterSize filterSize]);
            
            % Show both noisy and filtered images in separate figures
            figure; % Create a new figure for the noisy image
            ax1 = axes;
            imshow(app.NoisyImage, 'Parent', ax1);
            title(ax1, 'Noisy Image');

            figure; % Create a new figure for the median filtered image
            ax2 = axes;
            imshow(app.FilteredImage,  'Parent', ax2);
            title(ax2,'Median Filtered Image');
        end

        % Button pushed function: ImageHistogramButton
        function ImageHistogramButtonPushed(app, event)
        if isempty(app.OriginalImage)
            errordlg('Please load an image first.', 'Error');
            return;
        end 
            % Compute and display the histogram
            figure;
            ax = axes;
            imhist(rgb2gray(app.OriginalImage));
            title(ax,'Image Histogram ');
            xlabel(ax,'Intensity');
            ylabel(ax,'Pixels');
        end

        % Button pushed function: CannyOperationButton
        function CannyOperationButtonPushed(app, event)
        % Create a webcam object
app.WebcamObj = webcam; % Connect to the webcam

% Create a new figure for displaying the live video and Canny edges
hFig = figure('Name', 'Canny Operation', 'NumberTitle', 'off', ...
              'Position', [100, 100, 800, 400]);

% Create axes for original and Canny edge images
ax1 = subplot(1, 2, 1, 'Parent', hFig);
ax2 = subplot(1, 2, 2, 'Parent', hFig);

% Set up a cleanup function to clear the webcam object when the figure is closed
cleanupObj = onCleanup(@() clearWebcam(app.WebcamObj));

% Loop to continuously capture frames
while ishandle(hFig)
    % Capture an image from the webcam
    img = snapshot(app.WebcamObj);
    
    % Convert the image to grayscale
    grayImg = rgb2gray(img);
    
    % Apply Canny edge detection
    edges = edge(grayImg, 'Canny');
    
    % Display the original image
    imshow(img, 'Parent', ax1);
    title(ax1, 'Camera Image');
    
    % Display the Canny edge image
    imshow(edges, 'Parent', ax2);
    title(ax2, 'Canny Operation');
    
    pause(0.1); % Pause to allow for a smoother display
end

    clear webcamObj; % Clear the webcam object

        end

        % Button pushed function: ImageThresholdingButton
        function ImageThresholdingButtonPushed(app, event)
         if isempty(app.OriginalImage)
        errordlg('Please load an image first.', 'Error');
        return;
        end

    % Convert to grayscale if the image is in RGB format
    if size(app.OriginalImage, 3) == 3
        grayImage = rgb2gray(app.OriginalImage);
    else
        grayImage = app.OriginalImage; % Already grayscale
    end

    % Apply Otsu's method for automatic thresholding
    level = graythresh(grayImage);
    binaryImage = imbinarize(grayImage, level);

    % Display the thresholded image
    figure;
    ax = axes;
    imshow(binaryImage, 'Parent', ax);
    title(ax, sprintf('Thresholded Image (Level: %.2f)', level));
        end

        % Button pushed function: EdgeDetectionButton
        function EdgeDetectionButtonPushed(app, event)
    if isempty(app.OriginalImage)
        errordlg('Please load an image first.', 'Error');
        return;
    end

    % Convert to grayscale if necessary
    if size(app.OriginalImage, 3) == 3
        grayImage = rgb2gray(app.OriginalImage);
    else
        grayImage = app.OriginalImage;
    end

    % Sobel edge detection
    sobelEdges = edge(grayImage, 'Sobel');

    % Canny edge detection
    cannyEdges = edge(grayImage, 'Canny');

    % Display the Sobel and Canny edge detection results side by side
    figure;

    % Display Sobel edges
    subplot(1, 2, 1);
    imshow(sobelEdges);
    title('Sobel Edge Detection');

    % Display Canny edges
    subplot(1, 2, 2);
    imshow(cannyEdges);
    title('Canny Edge Detection');
        end

        % Button pushed function: HoughTransformButton
        function HoughTransformButtonPushed(app, event)
    if isempty(app.OriginalImage)
        errordlg('Please load an image first.', 'Error');
        return;
    end

    % Convert to grayscale if necessary
    if size(app.OriginalImage, 3) == 3
        grayImage = rgb2gray(app.OriginalImage);
    else
        grayImage = app.OriginalImage;
    end

    % Detect edges using the Canny method
    edges = edge(grayImage, 'Canny');

    % Perform Hough transform
    [H, T, R] = hough(edges);

    % Find peaks in the Hough transform
    peaks = houghpeaks(H, 10, 'Threshold', ceil(0.3 * max(H(:))));

    % Detect lines
    lines = houghlines(edges, T, R, peaks, 'FillGap', 20, 'MinLength', 30);

    % Display the original image with lines overlaid
    figure;
    imshow(app.OriginalImage);
    hold on;

    % Plot detected lines
    for k = 1:length(lines)
        xy = [lines(k).point1; lines(k).point2];
        plot(xy(:, 1), xy(:, 2), 'LineWidth', 2, 'Color', 'green');
        % Plot beginnings and ends of lines
        plot(xy(1, 1), xy(1, 2), 'x', 'LineWidth', 2, 'Color', 'yellow');
        plot(xy(2, 1), xy(2, 2), 'x', 'LineWidth', 2, 'Color', 'red');
    end

    title('Hough Transform - Line Detection');
    hold off;
        end

        % Button pushed function: HistogramEqualizationButton
        function HistogramEqualizationButtonPushed(app, event)
            
    % Check if an image has been uploaded
    if isempty(app.OriginalImage)
        errordlg('Please upload an image first.', 'Error');
        return;
    end

    % Convert to grayscale if necessary
    if size(app.OriginalImage, 3) == 3
        grayImage = rgb2gray(app.OriginalImage);
    else
        grayImage = app.OriginalImage;
    end

    % Perform histogram equalization and get the transformation curve
    [equalizedImage, transformationCurve] = histeq(grayImage);

    % Plot the transformation curve and histograms
    figure;
    subplot(3, 2, [1, 2]);
    plot(linspace(0, 1, 256), transformationCurve, 'LineWidth', 2);
    xlabel('Input Intensity');
    ylabel('Output Intensity');
    title('Transformation Curve for Histogram Equalization');
    grid on;

    % Display the original and equalized images
    subplot(3, 2, 3);
    imshow(grayImage);
    title('Original Grayscale Image');

    subplot(3, 2, 4);
    imshow(equalizedImage);
    title('Histogram Equalized Image');

    % Display the histograms
    subplot(3, 2, 5);
    imhist(grayImage);
    title('Histogram of Original Image');

    subplot(3, 2, 6);
    imhist(equalizedImage);
    title('Histogram of Equalized Image');

        end

        % Button pushed function: CrossCorrelationButton
        function CrossCorrelationButtonPushed(app, event)
           
    % Check if an image has been uploaded
    if isempty(app.OriginalImage)
        errordlg('Please upload an image first.', 'Error');
        return;
    end

    % Convert to grayscale if necessary
    if size(app.OriginalImage, 3) == 3
        grayImage = rgb2gray(app.OriginalImage);
    else
        grayImage = app.OriginalImage;
    end

    % Use the same image as both template and search image
    templateImage = grayImage; % Template image
    searchImage = grayImage;   % Search image

    % Perform normalized cross-correlation
    cross = normxcorr2(searchImage, templateImage);

    % Display the cross-correlation result
    figure;
    imshow(cross, []);
    title('Normalized Cross-Correlation');

    % Display the correlation surface
    figure;
    surf(cross), shading flat;
    title('Correlation Surface');

    % Find matching points
    [y, x] = find(cross > 0.96 * max(cross(:)));
    ynew = y - size(searchImage, 1);
    xnew = x - size(searchImage, 2);

    % Display matching points on the original image
    figure;
    imshow(app.OriginalImage); % Use the original image (color if applicable)
    title('Matching Points');
    hold on;
    for i = 1:length(xnew)
        rectangle('Position', [xnew(i) + 1, ynew(i) + 1, size(searchImage, 2), size(searchImage, 1)], ...
                  'EdgeColor', 'r', 'LineWidth', 1);
    end
    hold off;
        end

        % Button pushed function: MagnitudeofGradientButton
        function MagnitudeofGradientButtonPushed(app, event)
         
    % Check if an image has been uploaded
    if isempty(app.OriginalImage)
        errordlg('Please upload an image first.', 'Error');
        return;
    end

    % Convert to grayscale if necessary
    if size(app.OriginalImage, 3) == 3
        grayImage = rgb2gray(app.OriginalImage);
    else
        grayImage = app.OriginalImage;
    end

    % Compute gradients
    [GmagSobel, ~] = imgradient(grayImage, 'sobel'); % Sobel gradient
    [GmagPrewitt, ~] = imgradient(grayImage, 'prewitt'); % Prewitt gradient
    [GmagRoberts, ~] = imgradient(grayImage, 'roberts'); % Roberts gradient

    % Display the gradients in a new figure
    figure('Position', [100, 100, 1200, 400]);  %width and heigh
    subplot(1, 3, 1);
    imshow(GmagSobel, []);
    title('Sobel Gradient Operator');

    subplot(1, 3, 2);
    imshow(GmagPrewitt, []);
    title('Prewitt Gradient Operator');

    subplot(1, 3, 3);
    imshow(GmagRoberts, []);
    title('Roberts Gradient Operator');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.BASICIMAGEPROCESSINGLabel = uilabel(app.UIFigure);
            app.BASICIMAGEPROCESSINGLabel.Text = 'BASIC IMAGE PROCESSING';
            app.GrayscaleButton = uibutton(app.UIFigure, 'push');
            app.GrayscaleButton.ButtonPushedFcn = createCallbackFcn(app, @GrayscaleButtonPushed, true);
            app.GrayscaleButton.Text = 'Grayscale';
            app.MedianFilteringButton = uibutton(app.UIFigure, 'push');
            app.MedianFilteringButton.ButtonPushedFcn = createCallbackFcn(app, @MedianFilteringButtonPushed, true);
            app.MedianFilteringButton.Text = 'Median Filtering';
            app.ImageHistogramButton = uibutton(app.UIFigure, 'push');
            app.ImageHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @ImageHistogramButtonPushed, true);
            app.ImageHistogramButton.Text = 'Image Histogram';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.CannyOperationButton = uibutton(app.UIFigure, 'push');
            app.CannyOperationButton.ButtonPushedFcn = createCallbackFcn(app, @CannyOperationButtonPushed, true);
            app.CannyOperationButton.Text = 'Canny Operation';
            app.ImageThresholdingButton = uibutton(app.UIFigure, 'push');
            app.ImageThresholdingButton.ButtonPushedFcn = createCallbackFcn(app, @ImageThresholdingButtonPushed, true);
            app.ImageThresholdingButton.Text = 'Image Thresholding';
            app.EdgeDetectionButton = uibutton(app.UIFigure, 'push');
            app.EdgeDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @EdgeDetectionButtonPushed, true);
            app.EdgeDetectionButton.Text = 'Edge Detection';
            app.HoughTransformButton = uibutton(app.UIFigure, 'push');
            app.HoughTransformButton.ButtonPushedFcn = createCallbackFcn(app, @HoughTransformButtonPushed, true);
            app.HoughTransformButton.Text = 'Hough Transform';
            app.HistogramEqualizationButton = uibutton(app.UIFigure, 'push');
            app.HistogramEqualizationButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramEqualizationButtonPushed, true);
            app.HistogramEqualizationButton.Text = 'Histogram Equalization';
            app.CrossCorrelationButton = uibutton(app.UIFigure, 'push');
            app.CrossCorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @CrossCorrelationButtonPushed, true);
            app.CrossCorrelationButton.Text = 'Cross - Correlation';
            app.MagnitudeofGradientButton = uibutton(app.UIFigure, 'push');
            app.MagnitudeofGradientButton.ButtonPushedFcn = createCallbackFcn(app, @MagnitudeofGradientButtonPushed, true);
            app.MagnitudeofGradientButton.Text = 'Magnitude of Gradient';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = basic_image_processing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end