classdef partB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FINDCIRCLEButton        matlab.ui.control.Button
        OPENTHETESTIMAGEButton  matlab.ui.control.Button
        Image2                  matlab.ui.control.UIAxes
        Image                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
         CurrentImage            % Property to store the current image
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OPENTHETESTIMAGEButton
        function OPENTHETESTIMAGEButtonPushed(app, event)
    % Define the initial folder (replace with your actual path)
    initialFolder = 'C:\Users\Farah\Desktop\unimas\year 3\semester 1\computer vision\assignment\group assignment\PART B';
    
    % Open a file dialog to select an image, starting in the specified folder
    [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp)'}, ...
        'Select Test Image', initialFolder);
    
    if isequal(file, 0)
        return; % User canceled the dialog
    end

    % Read and display the selected image
    imgPath = fullfile(path, file);
    app.CurrentImage = imread(imgPath);
    imshow(app.CurrentImage, 'Parent', app.Image); % Display in the first uiaxes component
        end

        % Button pushed function: FINDCIRCLEButton
        function FINDCIRCLEButtonPushed(app, event)
     if isempty(app.CurrentImage)
        uialert(app.UIFigure, 'Please open an image first.', 'No Image Selected');
        return;
    end

    % Convert the image to grayscale
    grayImage = rgb2gray(app.CurrentImage);

    % Use edge detection (Sobel or Canny)
    edgeImage = edge(grayImage, 'sobel');  % You can try 'canny' for better results

    % Use Hough Transform to detect circles
    [centers, radii] = imfindcircles(edgeImage, [5 20], 'Sensitivity', 0.9, 'EdgeThreshold', 0.1); 

    % Initialize the number of circles
    number_of_circles = length(centers); % Count the number of detected circles

    % Display the original image and detected circles
    imshow(app.CurrentImage, 'Parent', app.Image2); % Display in the second uiaxes component
    hold(app.Image2, 'on');
    
    if ~isempty(centers)
        % Draw red circles on the detected locations
        viscircles(centers, radii, 'EdgeColor', 'r');
        message = sprintf('The number of circles is %d', length(centers));
        msgbox(message); % Display the message box with the count

    else
        % If no circles detected, show a message
        title(app.Image2, 'No circles detected');
    end
    
    hold(app.Image2, 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiaxes(app.UIFigure);
            title(app.Image, 'Image')
            xlabel(app.Image, 'X')
            ylabel(app.Image, 'Y')
            zlabel(app.Image, 'Z')
            app.Image2 = uiaxes(app.UIFigure);
            title(app.Image2, 'Detected Circles')
            xlabel(app.Image2, 'X')
            ylabel(app.Image2, 'Y')
            zlabel(app.Image2, 'Z')
            app.OPENTHETESTIMAGEButton = uibutton(app.UIFigure, 'push');
            app.OPENTHETESTIMAGEButton.ButtonPushedFcn = createCallbackFcn(app, @OPENTHETESTIMAGEButtonPushed, true);
            app.OPENTHETESTIMAGEButton.Text = ' OPEN THE TEST IMAGE ';
            app.FINDCIRCLEButton = uibutton(app.UIFigure, 'push');
            app.FINDCIRCLEButton.ButtonPushedFcn = createCallbackFcn(app, @FINDCIRCLEButtonPushed, true);
            app.FINDCIRCLEButton.Text = ' FIND CIRCLE ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = partB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end